classdef Prueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ComunicationInterfacePanel  matlab.ui.container.Panel
        SendButton                  matlab.ui.control.Button
        SendTerminal                matlab.ui.control.EditField
        RecieveTerminal             matlab.ui.control.EditField
        UserDataCount               matlab.ui.control.NumericEditField
        CounterButton               matlab.ui.control.Button
        ShowButton                  matlab.ui.control.Button
        ComunicationControlPanel    matlab.ui.container.Panel
        Lamp                        matlab.ui.control.Lamp
        BufferButton                matlab.ui.control.Button
        BufferCount                 matlab.ui.control.NumericEditField
        EndButton                   matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        device = [];
        btvCount = 1;
    end
    
    methods (Access = private)
        
        function readSerialData(app,src,~)
            data = readline(src);
            
            src.UserData.Data(app.device.UserData.Count)= data;
            src.UserData.Count = src.UserData.Count + 1;
            set(app.ShowButton,"Enable","on");
        end

        function controlInterfaz(app,state)
            %Guardamos componentes relevantes
            components =  [app.SendButton,app.CounterButton,app.ShowButton,app.BufferButton,app.EndButton,app.StartButton];
            set(components,'Enable',state);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            %Definimos y establecemos canal serie
            app.device = serialport(serialportlist,9600);
            configureTerminator(app.device,"CR/LF","CR/LF");
            flush(app.device);
            configureCallback(app.device,"terminator",@(src, event) readSerialData(app,src,event));
            app.device.UserData.Count = 1;
            
            %Asentamos la comunicación
            app.Lamp.Color = [1.00,0.00,0.00];
            pause(5.0);
            app.Lamp.Color = [0.00,1.00,0.00];
            
            %Bloqueamos partes sensibles
            set(app.ShowButton,"Enable","off");
            set(app.StartButton,"Enable","off");
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.device = [];
            set(app.StartButton,"Enable","on");
            controlInterfaz(app,'on');
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            app.device.UserData.Data(app.btvCount)
            app.RecieveTerminal.Value = app.device.UserData.Data(app.btvCount);
            app.btvCount = app.btvCount + 1;
        end

        % Button pushed function: BufferButton
        function BufferButtonPushed(app, event)
            app.BufferCount.Value = app.device.NumBytesAvailable;
        end

        % Button pushed function: CounterButton
        function CounterButtonPushed(app, event)
            app.UserDataCount.Value = app.device.UserData.Count;
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            if (app.SendTerminal.Value ~= ' ')
                writeline(app.device,app.SendTerminal.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComunicationControlPanel = uipanel(app.UIFigure);
            app.ComunicationControlPanel.Title = 'Comunication Control';
            app.StartButton = uibutton(app.ComunicationControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndButton = uibutton(app.ComunicationControlPanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.BufferCount = uieditfield(app.ComunicationControlPanel, 'numeric');
            app.BufferButton = uibutton(app.ComunicationControlPanel, 'push');
            app.BufferButton.ButtonPushedFcn = createCallbackFcn(app, @BufferButtonPushed, true);
            app.BufferButton.Text = 'Buffer';
            app.Lamp = uilamp(app.ComunicationControlPanel);
            app.ComunicationInterfacePanel = uipanel(app.UIFigure);
            app.ComunicationInterfacePanel.Title = 'Comunication Interface';
            app.ShowButton = uibutton(app.ComunicationInterfacePanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.CounterButton = uibutton(app.ComunicationInterfacePanel, 'push');
            app.CounterButton.ButtonPushedFcn = createCallbackFcn(app, @CounterButtonPushed, true);
            app.CounterButton.Text = 'Counter';
            app.UserDataCount = uieditfield(app.ComunicationInterfacePanel, 'numeric');
            app.RecieveTerminal = uieditfield(app.ComunicationInterfacePanel, 'text');
            app.SendTerminal = uieditfield(app.ComunicationInterfacePanel, 'text');
            app.SendButton = uibutton(app.ComunicationInterfacePanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prueba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end